classdef Signal_box < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SignalCreatorDropDownLabel  matlab.ui.control.Label
        SignalCreatorDropDown       matlab.ui.control.DropDown
        SignalAnalyzeDropDownLabel  matlab.ui.control.Label
        SignalAnalyzeDropDown       matlab.ui.control.DropDown
        SignalFilterDropDownLabel   matlab.ui.control.Label
        SignalFilterDropDown        matlab.ui.control.DropDown
        SCP                         matlab.ui.control.UIAxes
        SAP                         matlab.ui.control.UIAxes
        SFP                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        x
        t
        
    end


    methods (Access = private)

        % Value changed function: SignalCreatorDropDown
        function SignalCreatorDropDownValueChanged(app, event)
            value = app.SignalCreatorDropDown.Value;
            app.t = 0:0.001:0.5;
            if(strcmp(value,'square wave'))
                app.x = square(2*pi*10*app.t,50);
            elseif(strcmp(value,'triangular wave'))
                app.x = sawtooth(2*pi*10*app.t,0.5);
            elseif(strcmp(value,'sine'))
                app.x = sin(2*pi*10*app.t+1/3*pi);
            else
                app.x = 1+0.2*randn(1,length(app.t));
            end
            plot(app.SCP,app.t,app.x);
        end

        % Value changed function: SignalAnalyzeDropDown
        function SignalAnalyzeDropDownValueChanged(app, event)
            value = app.SignalAnalyzeDropDown.Value;
            NFET = 2^nextpow2(length(app.t));
            X = fft(app.x,NFET)/length(app.t);
            if(strcmp(value,'spectrum analysis'))
                plot(app.SAP,[1:1:NFET],abs(X));
            else
                Pyy = X.*conj(X)/length(app.t);%power spectrum %conj()共轭
                plot(app.SAP,[1:1:NFET],Pyy);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SignalCreatorDropDownLabel = uilabel(app.UIFigure);
            app.SignalCreatorDropDownLabel.Text = 'Signal Creator';
            app.SignalCreatorDropDown = uidropdown(app.UIFigure);
            app.SignalCreatorDropDown.Items = {'square wave', 'triangular wave', 'sine', 'gaussian white noise'};
            app.SignalCreatorDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalCreatorDropDownValueChanged, true);
            app.SignalCreatorDropDown.Value = 'square wave';
            app.SignalAnalyzeDropDownLabel = uilabel(app.UIFigure);
            app.SignalAnalyzeDropDownLabel.Text = 'Signal Analyze';
            app.SignalAnalyzeDropDown = uidropdown(app.UIFigure);
            app.SignalAnalyzeDropDown.Items = {'spectrum analysis', 'power spectrum analysis'};
            app.SignalAnalyzeDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalAnalyzeDropDownValueChanged, true);
            app.SignalAnalyzeDropDown.Value = 'spectrum analysis';
            app.SignalFilterDropDownLabel = uilabel(app.UIFigure);
            app.SignalFilterDropDownLabel.Text = 'Signal Filter';
            app.SignalFilterDropDown = uidropdown(app.UIFigure);
            app.SignalFilterDropDown.Items = {'LPF', 'BPF', 'HPF'};
            app.SignalFilterDropDown.Value = 'LPF';
            app.SCP = uiaxes(app.UIFigure);
            title(app.SCP, 'Title')
            xlabel(app.SCP, 'X')
            ylabel(app.SCP, 'Y')
            app.SAP = uiaxes(app.UIFigure);
            title(app.SAP, 'Title')
            xlabel(app.SAP, 'X')
            ylabel(app.SAP, 'Y')
            app.SFP = uiaxes(app.UIFigure);
            title(app.SFP, 'Title')
            xlabel(app.SFP, 'X')
            ylabel(app.SFP, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Signal_box

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end