classdef Signal_box_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        AmpEditFieldLabel          matlab.ui.control.Label
        AmpEditField               matlab.ui.control.NumericEditField
        SignalFilterListBoxLabel   matlab.ui.control.Label
        SignalFilter               matlab.ui.control.ListBox
        FsKnobLabel                matlab.ui.control.Label
        Fs                         matlab.ui.control.Knob
        SignalCreatorListBoxLabel  matlab.ui.control.Label
        SignalCreator              matlab.ui.control.ListBox
        SignalAnalyzeListBoxLabel  matlab.ui.control.Label
        SignalAnalyze              matlab.ui.control.ListBox
        TextAreaLabel              matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
    end


    properties (Access = private)
        t % Description
        x
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AmpEditField.Value = 1;
            app.SignalCreator.Value = 'Square';
            app.SignalFilter.Value = 'None';
        end

        % Value changed function: SignalCreator
        function SignalCreatorValueChanged(app, event)

        end

        % Value changing function: Fs
        function FsValueChanging(app, event)
            changingValue = event.Value;
            f = changingValue;
            app.TextArea.Value = num2str(changingValue);
            app.t = 0:0.001:1;
            NFET = 2^nextpow2(length(app.t));
            value0 = app.SignalCreator.Value;
            value1 = app.SignalAnalyze.Value;
            switch value0
                case 'Square'
                    app.x = square(2*pi*f*app.t,50);
                case 'Triangular'
                    app.x = sawtooth(2*pi*f*app.t,0.5);
                case 'Sine'
                    app.x = sin(2*pi*f*app.t+1/3*pi);
                otherwise
                    app.x = 1+0.2*randn(1,length(app.t));
            end
            X = fft(app.x,NFET)/length(app.t);
            switch value1
                case 'Spectrum analysis'
                    app.x = abs(X);
                    app.t = [1:1:NFET];
                case 'Power spectrum analysis'
                    Pyy = X.*conj(X)/length(app.t);%power spectrum %conj()共轭
                    app.x = abs(Pyy);
                    app.t = [1:1:NFET];
                otherwise
                    app.x = app.x;
                    app.t = app.t;
            end
            plot(app.UIAxes, app.t, app.x);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.AmpEditFieldLabel = uilabel(app.UIFigure);
            app.AmpEditFieldLabel.Text = 'Amp';
            app.AmpEditField = uieditfield(app.UIFigure, 'numeric');
            app.SignalFilterListBoxLabel = uilabel(app.UIFigure);
            app.SignalFilterListBoxLabel.Text = 'Signal Filter';
            app.SignalFilter = uilistbox(app.UIFigure);
            app.SignalFilter.Items = {'None', 'LPF', 'BPF', 'HPF'};
            app.SignalFilter.Value = 'None';
            app.FsKnobLabel = uilabel(app.UIFigure);
            app.FsKnobLabel.Text = 'Fs';
            app.Fs = uiknob(app.UIFigure, 'continuous');
            app.Fs.Limits = [1 10];
            app.Fs.ValueChangingFcn = createCallbackFcn(app, @FsValueChanging, true);
            app.Fs.Value = 10;
            app.SignalCreatorListBoxLabel = uilabel(app.UIFigure);
            app.SignalCreatorListBoxLabel.Text = 'Signal Creator';
            app.SignalCreator = uilistbox(app.UIFigure);
            app.SignalCreator.Items = {'Square', 'Triangular', 'Sine', 'Multi-Sine'};
            app.SignalCreator.ValueChangedFcn = createCallbackFcn(app, @SignalCreatorValueChanged, true);
            app.SignalCreator.Value = 'Square';
            app.SignalAnalyzeListBoxLabel = uilabel(app.UIFigure);
            app.SignalAnalyzeListBoxLabel.Text = 'Signal Analyze';
            app.SignalAnalyze = uilistbox(app.UIFigure);
            app.SignalAnalyze.Items = {'None', 'Spectrum analysis', 'Power spectrum analysis'};
            app.SignalAnalyze.Value = 'None';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Signal_box_v2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end