classdef Signal_box_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NoiseS_dBLabel                 matlab.ui.control.Label
        Noise                          matlab.ui.control.DiscreteKnob
        DSPLabel                       matlab.ui.control.Label
        PeriodKnobLabel                matlab.ui.control.Label
        Period                         matlab.ui.control.Knob
        FIlterLabel                    matlab.ui.control.Label
        FilterOn                       matlab.ui.control.Switch
        FilterOnLampLabel              matlab.ui.control.Label
        FilterOnLamp                   matlab.ui.control.Lamp
        Panel                          matlab.ui.container.Panel
        add                            matlab.ui.control.Button
        minus                          matlab.ui.control.Button
        AmpEditFieldLabel              matlab.ui.control.Label
        Amp                            matlab.ui.control.NumericEditField
        SignalSelectDropDownLabel      matlab.ui.control.Label
        SignalSelect                   matlab.ui.control.DropDown
        Modulation                     matlab.ui.control.StateButton
        Mul                            matlab.ui.control.StateButton
        PhaLabel                       matlab.ui.control.Label
        Pha                            matlab.ui.control.Slider
        SampleFrequencyEditFieldLabel  matlab.ui.control.Label
        SampleFrequencyEditField       matlab.ui.control.NumericEditField
        AnalyzeLabel                   matlab.ui.control.Label
        Analyze                        matlab.ui.control.DiscreteKnob
        FilterLabel                    matlab.ui.control.Label
        Filter                         matlab.ui.control.DiscreteKnob
        TabGroup                       matlab.ui.container.TabGroup
        SignalDisplayTab               matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        FilterDisplayTab               matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        FilterTypeButtonGroup          matlab.ui.container.ButtonGroup
        IIRButton                      matlab.ui.control.ToggleButton
        FIRButton                      matlab.ui.control.ToggleButton
        FilterTypeButtonGroup_2        matlab.ui.container.ButtonGroup
        ButtersorthButton              matlab.ui.control.ToggleButton
        ChebyshevIButton               matlab.ui.control.ToggleButton
        ChebyshevIIButton              matlab.ui.control.ToggleButton
        BandPanel                      matlab.ui.container.Panel
        StopSliderLabel                matlab.ui.control.Label
        Stop_up                        matlab.ui.control.Slider
        StopSlider_2Label              matlab.ui.control.Label
        Stop_down                      matlab.ui.control.Slider
        PassSlider_2Label              matlab.ui.control.Label
        Pass_down                      matlab.ui.control.Slider
        PassSlider_2Label_2            matlab.ui.control.Label
        Pass_up                        matlab.ui.control.Slider
        RpSpinnerLabel                 matlab.ui.control.Label
        RpSpinner                      matlab.ui.control.Spinner
        RsSpinnerLabel                 matlab.ui.control.Label
        RsSpinner                      matlab.ui.control.Spinner
    end


    properties (Access = private)
        autoupdate % Description
        F % Description
        xx % Description
        tt % Description
        multix % Description
        ph % Description
        pup % Description
        pdown % Description
        sup % Description
        sdown % Description
        ff % Description
        aa % Description
        bb % Description
    end

    methods (Access = private)
        function updatePlot(app)
            results0 = generateSignal(app);
            results1 = depositSignal(app,results0(1,:),results0(2,:));
            if(strcmp(app.Analyze.Value,'Spectrum'))
                stem(app.UIAxes, results1(1,:), results1(2,:));
            else
                plot(app.UIAxes, results1(1,:), results1(2,:));
            end
            app.UIAxes.YLim = [min(results1(2,:)),max(results1(2,:))];
        end
    
        function updateFilter(app)
            if(app.IIRButton.Value==1)
                results = IIR_Filter(app);
                app.ff = results(2,:);
                plot(app.UIAxes2, results(1,:),results(2,:));
                app.UIAxes2.YLim = [min(results(2,:)),max(results(2,:))];
                plot(app.UIAxes3, results(1,:),results(3,:),'r');
                app.UIAxes3.YLim = [min(results(3,:)),max(results(3,:))];
            else
                results = FIR_Filter(app);
                app.ff = results(2,:);
                plot(app.UIAxes2, results(1,:),results(2,:));
                app.UIAxes2.YLim = [min(results(2,:)),max(results(2,:))];
                plot(app.UIAxes3, results(1,:),results(3,:),'r');
                app.UIAxes3.YLim = [min(results(3,:)),max(results(3,:))];
            end
        end
        
        function results = generateSignal(app)
%           Get value
            signal = app.SignalSelect.Value;
            amp = app.Amp.Value; pha = app.ph/360*2*pi;
            f = app.F; noise = str2double(app.Noise.Value);
%           Set Default Value
            fs = 1000;
            t = 0:1/fs:1-1/fs;
%           Create chosen signal
            switch signal
                case 'None'
                    x = ones(1,length(t));
                case 'Square'
                    x = square(2*pi*f*t,50);
                case 'Triangular'
                    x = sawtooth(2*pi*f*t,0.5);
                case 'Sine'
                    x = sin(2*pi*f*t+pha);
                case 'WAV'
                    temp = audioread('7577.wav',[1,fs]);
                    x = temp(:,1)';
                otherwise
                    x = zeros(length(t));
            end
            
            x = x * amp;
            x = awgn(x,noise,'measured');
            
            results = [t; x];
        end   
    
        function results = depositSignal(app,ti,xi)

            if(app.Mul.Value)
                app.multix = xi;
            else
                app.tt = ti;
                app.xx = xi;
            end
            t = app.tt;x = app.xx;
            
            analyze = app.Analyze.Value;

%           Modulation
            if(app.Modulation.Value)
                x = x.*cos(2*pi*100*t);
            end
            
            if strcmp(app.FilterOn.Value,'on')
                temp = filter(app.bb, app.aa, x);
                x = temp(1:1000);
                app.FilterOnLamp.Color = [0 1 0];
            else
                app.FilterOnLamp.Color = [0.5 0.5 0.5];
            end
            
%           Signal Analyze
            switch analyze
                case 'Power'
                    results = powerAnalyze(app,x);
                case 'Spectrum'
                    results = time2freq(app,t,x);
                otherwise
                    results = [t;x];
            end
        end
        
%       Convert time signal to frequency signal
        function results = time2freq(~,t,x)
            NFET = 2^nextpow2(length(t));
            X = fft(x, NFET)/length(t);
            results = [NFET/1.1:NFET;abs(X(NFET/1.1:NFET))];
        end
        
        function results = powerAnalyze(~,x)
            window=boxcar(length(x));
            [Pxx,f]=periodogram(x,window,length(x),1000);
            X = 10*log10(Pxx);
            results = [f'; X'];
        end
        
        
%       IIR Filter Design
        function results = IIR_Filter(app)
            fs = 1000;
            switch app.Filter.Value
                case 'LPF'
                    wp=app.pup/fs; ws=app.sup/fs;
                case 'BPF'
                    wp=[app.pup/fs app.pdown/fs]; ws=[app.sup/fs app.sdown/fs];
                case 'HPF'
                    wp=app.sdown/fs; ws=app.pdown/fs;
                case 'BSF'
                    ws=[app.pup/fs app.pdown/fs]; wp=[app.sup/fs app.sdown/fs];
            end
            
            if(app.ButtersorthButton.Value==1)
                results = IIR_Butterword(app,wp,ws);
            elseif(app.ChebyshevIIButton.Value==1)
                results = IIR_ChebyshevI(app,wp,ws);
            elseif(app.ChebyshevIButton.Value==1)
                results = IIR_ChebyshevII(app,wp,ws);
            end
        end
%       Low Pass Filter
        function results = IIR_Butterword(app,wp,ws)
            Rp = 3; Rs = 80;
            [n,Wn] = buttord(wp,ws,Rp,Rs);
            switch app.Filter.Value
                case 'LPF'
                    [b,a] = butter(n,Wn,'low');
                case 'BPF'
                    [b,a] = butter(n,Wn,'bandpass');
                case 'HPF'
                    [b,a] = butter(n,Wn,'high');
                case 'BSF'
                    [b,a] = butter(n,Wn,'stop');
            end
            app.bb = b;app.aa = a;
            [h,w] = freqz(b,a,512,1000);
            results = [(w/pi)';(20*log10(abs(h)))';(180/pi*(unwrap(angle(h))))'];
        end
        
        function results = IIR_ChebyshevI(app,wp,ws)
            Rp = 3; Rs = 80;
            [n,~] = cheb1ord(wp,ws,Rp,Rs);
            switch app.Filter.Value
                case 'LPF'
                    [b,a] = cheby1(n,Rp,wp,'low');
                case 'BPF'
                    [b,a] = cheby1(n,Rp,wp,'bandpass');
                case 'HPF'
                    [b,a] = cheby1(n,Rp,wp,'high');
                case 'BSF'
                    [b,a] = cheby1(n,Rp,wp,'stop');
            end
            app.bb = b;app.aa = a;
            [h,w] = freqz(b,a,512,1000);
            results = [(w/pi)';(20*log10(abs(h)))';(180/pi*(unwrap(angle(h))))'];
        end
        
        function results = IIR_ChebyshevII(app,wp,ws)
            Rp = 3; Rs = 80;
            [n,~] = cheb2ord(wp,ws,Rp,Rs);
            switch app.Filter.Value
                case 'LPF'
                    [b,a] = cheby2(n,Rp,wp,'low');
                case 'BPF'
                    [b,a] = cheby2(n,Rp,wp,'bandpass');
                case 'HPF'
                    [b,a] = cheby2(n,Rp,wp,'high');
                case 'BSF'
                    [b,a] = cheby2(n,Rp,wp,'stop');
            end
            app.bb = b;app.aa = a;
            [h,w] = freqz(b,a,512,1000);
            results = [(w/pi)';(20*log10(abs(h)))';(180/pi*(unwrap(angle(h))))'];
        end
    
%       FIR Filter Design
        function results = FIR_Filter(app)
            fs = 1000;as = 80;
            switch app.Filter.Value
                case 'LPF'                    
                    wp=app.pup/fs; ws=app.sup/fs;
                    width = abs(wp-ws);
                    N = ceil(8*pi/width);
                    wn = (wp+ws)/2;
                    b = fir1(N,wn,hanning(N+1));app.bb = b;app.aa = 1;
                    [h,w] = freqz(b,1,512,1000);
                    results = [(w/pi)';(20*log10(abs(h)))';(180/pi*(unwrap(angle(h))))'];
                case 'BPF'                    
                    wp=[app.pup/fs app.pdown/fs]; ws=[app.sup/fs app.sdown/fs];
                    width=min(abs(ws(1)-wp(1)),abs(ws(2)-wp(2)));
                    N=ceil((as-7.95)/(2.285*width))+1;B=0.1102*(as-8.7);
                    win=kaiser(N,B)';
                    wc1=(wp(1)+ws(1))/2; wc2=(wp(2)+ws(2))/2;
                    b = fir1(N-1,[wc1,wc2],'bandpass',win);app.bb = b;app.aa = 1;
                    [h,w] = freqz(b,1,512,1000);
                    results = [(w/pi)';(20*log10(abs(h)))';(180/pi*(unwrap(angle(h))))'];
                case 'HPF'
                    wp=app.sdown/fs; ws=app.pdown/fs;
                    width = abs(wp-ws);
                    N = ceil(8*pi/width);
                    wn = (wp+ws)/2;
                    b = fir1(N,wn,'high',hanning(N+1));app.bb = b;app.aa = 1;
                    [h,w] = freqz(b,1,512,1000);
                    results = [(w/pi)';(20*log10(abs(h)))';(180/pi*(unwrap(angle(h))))'];
                case 'BSF'
                    ws=[app.pup/fs app.pdown/fs]; wp=[app.sup/fs app.sdown/fs];
                    width=min(abs(ws(1)-wp(1)),abs(ws(2)-wp(2)));
                    N=ceil((as-7.95)/(2.285*width))+1;B=0.1102*(as-8.7);
                    win=kaiser(N,B)';
                    wc1=(wp(1)+ws(1))/2; wc2=(wp(2)+ws(2))/2;
                    b = fir1(N-1,[wc1/pi,wc2/pi],'stop',win);app.bb = b;app.aa = 1;
                    [h,w] = freqz(b,1,512,1000);
                    results = [(w/pi)';(20*log10(abs(h)))';(180/pi*(unwrap(angle(h))))'];
            end
            
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Pass_up.Value = 20;
            app.Stop_up.Value = 30;
            app.Stop_down.Value = 50;
            app.Pass_down.Value = 60;
            
            app.ButtersorthButton.Value = 1;
            
            app.add.Enable = 'off';
            app.minus.Enable = 'off';
            
            app.Pass_up.Enable = 'on';
            app.Stop_up.Enable = 'on';
            app.Stop_down.Enable = 'off';
            app.Pass_down.Enable = 'off';
            
            app.Pha.Enable = 'off';
            
            app.F = 10; app.ph = 0;
            app.pup = 100; app.sup = 200;
            app.sdown = 400; app.pdown = 500;
            app.SignalSelect.Value = 'None';app.Analyze.Value = 'None';app.Amp.Value = 1;
            app.Filter.Value = 'LPF';
            
            updateFilter(app);updatePlot(app);
        end

        % Value changed function: SignalSelect
        function SignalSelectValueChanged(app, event)
            value = app.SignalSelect.Value;
            if(strcmp(value,'Sine'))
                app.Pha.Enable = 'on';
            else
                app.Pha.Enable = 'off';
            end
            updatePlot(app);
        end

        % Value changing function: Period
        function PeriodValueChanging(app, event)
            changingValue = event.Value;
            app.F = changingValue;
            
            updatePlot(app);
        end

        % Value changed function: Noise
        function NoiseValueChanged(app, event)
            value = app.Noise.Value;
            updatePlot(app);
        end

        % Callback function
        function SignalAnalyzeSelectionChanged(app, event)
            selectedButton = app.SignalAnalyze.SelectedObject;
            updatePlot(app);
        end

        % Value changed function: Analyze
        function AnalyzeValueChanged(app, event)
            value = app.Analyze.Value;
            updatePlot(app);
        end

        % Value changed function: Filter
        function FilterValueChanged(app, event)
            value = app.Filter.Value;
            switch value
                case 'LPF'
                    app.Pass_up.Enable = 'on';
                    app.Stop_up.Enable = 'on';
                    app.Stop_down.Enable = 'off';
                    app.Pass_down.Enable = 'off';
                case 'BPF'
                    app.Pass_up.Enable = 'on';
                    app.Stop_up.Enable = 'on';
                    app.Stop_down.Enable = 'on';
                    app.Pass_down.Enable = 'on';
                case 'BSF'
                    app.Pass_up.Enable = 'on';
                    app.Stop_up.Enable = 'on';
                    app.Stop_down.Enable = 'on';
                    app.Pass_down.Enable = 'on';
                case 'HPF'
                    app.Pass_up.Enable = 'off';
                    app.Stop_up.Enable = 'off';
                    app.Stop_down.Enable = 'on';
                    app.Pass_down.Enable = 'on';
            end
            updateFilter(app);
            updatePlot(app);
        end

        % Value changed function: FilterOn
        function FilterOnValueChanged(app, event)
            value = app.FilterOn.Value;
            updatePlot(app);
        end

        % Value changed function: Modulation
        function ModulationValueChanged(app, event)
            value = app.Modulation.Value;
            updatePlot(app);
        end

        % Value changed function: Mul
        function MulValueChanged(app, event)
            value = app.Mul.Value;
            if(value)
                app.add.Enable = 'on';
                app.minus.Enable = 'on';
            else
                app.add.Enable = 'off';
                app.minus.Enable = 'off';
            end
            updatePlot(app);
        end

        % Button pushed function: add
        function addButtonPushed(app, event)
            app.xx = app.xx + app.multix;
            updatePlot(app);
        end

        % Button pushed function: minus
        function minusButtonPushed(app, event)
            app.xx = app.xx - app.multix;
            updatePlot(app);
        end

        % Value changed function: Amp
        function AmpValueChanged(app, event)
            value = app.Amp.Value;
            updatePlot(app);
        end

        % Value changing function: Pha
        function PhaValueChanging(app, event)
            changingValue = event.Value;
            app.ph = changingValue;
            updatePlot(app);
        end

        % Value changing function: Pass_up
        function pass_upValueChanging(app, event)
            changingValue = event.Value;
            app.pup = changingValue;
            
            updateFilter(app);updatePlot(app);
        end

        % Value changing function: Stop_up
        function stop_upValueChanging(app, event)
            changingValue = event.Value;
            app.sup = changingValue;
            updateFilter(app);
            updatePlot(app);
        end

        % Value changing function: Stop_down
        function stop_downValueChanging(app, event)
            changingValue = event.Value;
            app.sdown = changingValue;
            updateFilter(app);updatePlot(app);
        end

        % Value changing function: Pass_down
        function pass_downValueChanging(app, event)
            changingValue = event.Value;
            app.pdown = changingValue;
            updateFilter(app);updatePlot(app);
        end

        % Callback function
        function ButtonPushed(app, event)
% 
        end

        % Selection changed function: FilterTypeButtonGroup_2
        function FilterTypeButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.FilterTypeButtonGroup_2.SelectedObject;
            updateFilter(app);
        end

        % Selection changed function: FilterTypeButtonGroup
        function FilterTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterTypeButtonGroup.SelectedObject;
            if(app.IIRButton.Value==1)
                app.FilterTypeButtonGroup_2.Visible = 'On';
            else
                app.FilterTypeButtonGroup_2.Visible = 'Off';
            end
            updateFilter(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NoiseS_dBLabel = uilabel(app.UIFigure);
            app.NoiseS_dBLabel.Text = 'Noise(S_dB)';
            app.Noise = uiknob(app.UIFigure, 'discrete');
            app.Noise.Items = {'100', '50', '20', '10', '1'};
            app.Noise.ValueChangedFcn = createCallbackFcn(app, @NoiseValueChanged, true);
            app.Noise.Value = '100';
            app.DSPLabel = uilabel(app.UIFigure);
            app.DSPLabel.Text = 'DSP';
            app.PeriodKnobLabel = uilabel(app.UIFigure);
            app.PeriodKnobLabel.Text = 'Period';
            app.Period = uiknob(app.UIFigure, 'continuous');
            app.Period.Limits = [10 100];
            app.Period.ValueChangingFcn = createCallbackFcn(app, @PeriodValueChanging, true);
            app.Period.Value = 10;
            app.FIlterLabel = uilabel(app.UIFigure);
            app.FIlterLabel.Text = 'FIlter';
            app.FilterOn = uiswitch(app.UIFigure, 'slider');
            app.FilterOn.Items = {'off', 'on'};
            app.FilterOn.ValueChangedFcn = createCallbackFcn(app, @FilterOnValueChanged, true);
            app.FilterOn.Value = 'off';
            app.FilterOnLampLabel = uilabel(app.UIFigure);
            app.FilterOnLampLabel.Text = 'Filter On';
            app.FilterOnLamp = uilamp(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.add = uibutton(app.Panel, 'push');
            app.add.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.add.Text = '+';
            app.minus = uibutton(app.Panel, 'push');
            app.minus.ButtonPushedFcn = createCallbackFcn(app, @minusButtonPushed, true);
            app.minus.Text = '-';
            app.AmpEditFieldLabel = uilabel(app.Panel);
            app.AmpEditFieldLabel.Text = 'Amp';
            app.Amp = uieditfield(app.Panel, 'numeric');
            app.Amp.ValueChangedFcn = createCallbackFcn(app, @AmpValueChanged, true);
            app.Amp.Value = 1;
            app.SignalSelectDropDownLabel = uilabel(app.Panel);
            app.SignalSelectDropDownLabel.Text = 'Signal Select';
            app.SignalSelect = uidropdown(app.Panel);
            app.SignalSelect.Items = {'None', 'Square', 'Triangular', 'Sine', 'WAV'};
            app.SignalSelect.ValueChangedFcn = createCallbackFcn(app, @SignalSelectValueChanged, true);
            app.SignalSelect.Value = 'None';
            app.Modulation = uibutton(app.Panel, 'state');
            app.Modulation.ValueChangedFcn = createCallbackFcn(app, @ModulationValueChanged, true);
            app.Modulation.Text = 'Modulation';
            app.Mul = uibutton(app.Panel, 'state');
            app.Mul.ValueChangedFcn = createCallbackFcn(app, @MulValueChanged, true);
            app.Mul.Text = 'Mul';
            app.PhaLabel = uilabel(app.Panel);
            app.PhaLabel.Text = 'Pha';
            app.Pha = uislider(app.Panel);
            app.Pha.Limits = [0 360];
            app.Pha.ValueChangingFcn = createCallbackFcn(app, @PhaValueChanging, true);
            app.SampleFrequencyEditFieldLabel = uilabel(app.Panel);
            app.SampleFrequencyEditFieldLabel.Text = 'Sample Frequency';
            app.SampleFrequencyEditField = uieditfield(app.Panel, 'numeric');
            app.SampleFrequencyEditField.Editable = 'off';
            app.SampleFrequencyEditField.Value = 1000;
            app.AnalyzeLabel = uilabel(app.UIFigure);
            app.AnalyzeLabel.Text = 'Analyze';
            app.Analyze = uiknob(app.UIFigure, 'discrete');
            app.Analyze.Items = {'None', 'Spectrum', 'Power'};
            app.Analyze.ValueChangedFcn = createCallbackFcn(app, @AnalyzeValueChanged, true);
            app.Analyze.Value = 'None';
            app.FilterLabel = uilabel(app.UIFigure);
            app.FilterLabel.Text = 'Filter';
            app.Filter = uiknob(app.UIFigure, 'discrete');
            app.Filter.Items = {'LPF', 'BPF', 'HPF', 'BSF'};
            app.Filter.ValueChangedFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Filter.Value = 'LPF';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SignalDisplayTab = uitab(app.TabGroup);
            app.SignalDisplayTab.Title = 'Signal Display';
            app.UIAxes = uiaxes(app.SignalDisplayTab);
            title(app.UIAxes, 'Signal Display')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FilterDisplayTab = uitab(app.TabGroup);
            app.FilterDisplayTab.Title = 'Filter Display';
            app.UIAxes2 = uiaxes(app.FilterDisplayTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.FilterDisplayTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.FilterTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterTypeButtonGroupSelectionChanged, true);
            app.FilterTypeButtonGroup.Title = 'FilterType';
            app.IIRButton = uitogglebutton(app.FilterTypeButtonGroup);
            app.IIRButton.Text = 'IIR';
            app.IIRButton.Value = true;
            app.FIRButton = uitogglebutton(app.FilterTypeButtonGroup);
            app.FIRButton.Text = 'FIR';
            app.FilterTypeButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.FilterTypeButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @FilterTypeButtonGroup_2SelectionChanged, true);
            app.FilterTypeButtonGroup_2.Title = 'FilterType';
            app.ButtersorthButton = uitogglebutton(app.FilterTypeButtonGroup_2);
            app.ButtersorthButton.Text = 'Buttersorth';
            app.ButtersorthButton.Value = true;
            app.ChebyshevIButton = uitogglebutton(app.FilterTypeButtonGroup_2);
            app.ChebyshevIButton.Text = 'Chebyshev I';
            app.ChebyshevIIButton = uitogglebutton(app.FilterTypeButtonGroup_2);
            app.ChebyshevIIButton.Text = 'Chebyshev II';
            app.BandPanel = uipanel(app.UIFigure);
            app.BandPanel.Title = 'Band';
            app.StopSliderLabel = uilabel(app.BandPanel);
            app.StopSliderLabel.Text = 'Stop';
            app.Stop_up = uislider(app.BandPanel);
            app.Stop_up.Limits = [20 120];
            app.Stop_up.ValueChangingFcn = createCallbackFcn(app, @stop_upValueChanging, true);
            app.Stop_up.Value = 30;
            app.StopSlider_2Label = uilabel(app.BandPanel);
            app.StopSlider_2Label.Text = 'Stop';
            app.Stop_down = uislider(app.BandPanel);
            app.Stop_down.Limits = [20 120];
            app.Stop_down.ValueChangingFcn = createCallbackFcn(app, @stop_downValueChanging, true);
            app.Stop_down.Value = 60;
            app.PassSlider_2Label = uilabel(app.BandPanel);
            app.PassSlider_2Label.Text = 'Pass';
            app.Pass_down = uislider(app.BandPanel);
            app.Pass_down.Limits = [20 120];
            app.Pass_down.ValueChangingFcn = createCallbackFcn(app, @pass_downValueChanging, true);
            app.Pass_down.Value = 70;
            app.PassSlider_2Label_2 = uilabel(app.BandPanel);
            app.PassSlider_2Label_2.Text = 'Pass';
            app.Pass_up = uislider(app.BandPanel);
            app.Pass_up.Limits = [20 120];
            app.Pass_up.ValueChangingFcn = createCallbackFcn(app, @pass_upValueChanging, true);
            app.Pass_up.Value = 20;
            app.RpSpinnerLabel = uilabel(app.UIFigure);
            app.RpSpinnerLabel.Text = 'Rp';
            app.RpSpinner = uispinner(app.UIFigure);
            app.RpSpinner.Value = 3;
            app.RsSpinnerLabel = uilabel(app.UIFigure);
            app.RsSpinnerLabel.Text = 'Rs';
            app.RsSpinner = uispinner(app.UIFigure);
            app.RsSpinner.Step = 10;
            app.RsSpinner.Value = 80;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Signal_box_v3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end